classdef DoAnMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nUIFigure                   matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NghiemTab                   matlab.ui.container.Tab
        NhpphngtrnhEditFieldLabel   matlab.ui.control.Label
        Nghiem_NhapPhuongTrinh      matlab.ui.control.EditField
        KhongphnlyabEditFieldLabel  matlab.ui.control.Label
        Nghiem_KhoangPhanLy_a       matlab.ui.control.NumericEditField
        Nghiem_KhoangPhanLy_b       matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel    matlab.ui.control.Label
        Nghiem_SaiSoChoPhep         matlab.ui.control.NumericEditField
        PhngphptmDropDownLabel      matlab.ui.control.Label
        Nghiem_PhuongPhap           matlab.ui.control.DropDown
        KtqunghimTextAreaLabel      matlab.ui.control.Label
        Nghiem_KetQua               matlab.ui.control.TextArea
        Nghiem_DoThi                matlab.ui.control.UIAxes
        SlnlpTextAreaLabel          matlab.ui.control.Label
        Nghiem_SoLanLap             matlab.ui.control.TextArea
        NhpfpEditFieldLabel         matlab.ui.control.Label
        Nghiem_Nhapfp               matlab.ui.control.EditField
        NoiSuyTab                   matlab.ui.container.Tab
        NhpdliuxyLabel              matlab.ui.control.Label
        NoiSuy_x                    matlab.ui.control.NumericEditField
        PhngphpnisuyLabel           matlab.ui.control.Label
        NoiSuy_PhuongPhap           matlab.ui.control.DropDown
        NoiSuy_y                    matlab.ui.control.NumericEditField
        GitrnisuyLabel              matlab.ui.control.Label
        NoiSuy_GiaTriNoiSuy         matlab.ui.control.NumericEditField
        NoiSuy_DoThi                matlab.ui.control.UIAxes
        PhngtrnhLabel               matlab.ui.control.Label
        NoiSuy_DaThuc               matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel      matlab.ui.control.Label
        NoiSuy_KetQua               matlab.ui.control.TextArea
        HoiQuyTab                   matlab.ui.container.Tab
        HoiQuy_y                    matlab.ui.control.NumericEditField
        NhpdliuxyLabel_2            matlab.ui.control.Label
        HoiQuy_x                    matlab.ui.control.NumericEditField
        GitrcnsuyonLabel            matlab.ui.control.Label
        HoiQuy_GiaTriSuyDoan        matlab.ui.control.NumericEditField
        PhngphpnisuyLabel_2         matlab.ui.control.Label
        HoiQuy_PhuongPhap           matlab.ui.control.DropDown
        PhngtrnhLabel_2             matlab.ui.control.Label
        HoiQuy_PhuongTrinh          matlab.ui.control.TextArea
        KtqudonLabel                matlab.ui.control.Label
        HoiQuy_KetQua               matlab.ui.control.TextArea
        HoiQuy_DoThi                matlab.ui.control.UIAxes
        DaoHamTab                   matlab.ui.container.Tab
        NhphmsLabel                 matlab.ui.control.Label
        DaoHam_HamSo                matlab.ui.control.EditField
        GitrcnohmLabel              matlab.ui.control.Label
        DaoHam_GiaTri               matlab.ui.control.NumericEditField
        GitrsaisSwitchLabel         matlab.ui.control.Label
        DaoHam_GiaTriSaiSo          matlab.ui.control.Switch
        PhngphpnisuyLabel_3         matlab.ui.control.Label
        DaoHam_PhuongPhap           matlab.ui.control.DropDown
        KtquTextAreaLabel           matlab.ui.control.Label
        DaoHam_KetQua               matlab.ui.control.TextArea
        KtquchnhxcLabel             matlab.ui.control.Label
        DaoHam_KetQuaChinhXac       matlab.ui.control.TextArea
        NhpdliuxyEditFieldLabel     matlab.ui.control.Label
        DaoHam_x                    matlab.ui.control.EditField
        DaoHam_y                    matlab.ui.control.EditField
        TichPhanTab                 matlab.ui.container.Tab
        NhpdliuxyLabel_4            matlab.ui.control.Label
        TichPhan_x                  matlab.ui.control.NumericEditField
        TichPhan_y                  matlab.ui.control.NumericEditField
        NhphmsLabel_2               matlab.ui.control.Label
        TichPhan_HamSo              matlab.ui.control.EditField
        NhpcnabLabel                matlab.ui.control.Label
        TichPhan_Can                matlab.ui.control.NumericEditField
        NhpNLabel                   matlab.ui.control.Label
        TichPhan_N                  matlab.ui.control.TextArea
        PhngphpnisuyLabel_4         matlab.ui.control.Label
        TIchPhan_PhuongPhap         matlab.ui.control.DropDown
        KtquTextAreaLabel_2         matlab.ui.control.Label
        TichPhan_KetQua             matlab.ui.control.TextArea
        GioiThieuTab                matlab.ui.container.Tab
    end


    methods (Access = private)

        % Value changed function: DaoHam_GiaTri, DaoHam_GiaTriSaiSo, 
        % DaoHam_HamSo, DaoHam_PhuongPhap, DaoHam_x, DaoHam_y
        function DaoHam_GiaTriValueChanged(app, event)
        addpath(fullfile(pwd, 'DoAnDaoHam')); %Duong dan file ham chuc nang

        x = str2double(split(app.DaoHam_x.Value));
        y = str2double(split(app.DaoHam_y.Value));
        
        diemdaoham = app.DaoHam_GiaTri.Value;
        phuongphap = app.DaoHam_PhuongPhap.Value;
        saiso = app.DaoHam_GiaTriSaiSo.Value;
        vitri = find(x == diemdaoham);
        hamso = app.DaoHam_HamSo.Value;
        fx = str2func(['@(x) ' hamso]);
        i = vitri;
        h = x(i+1)-x(i);
        app.DaoHam_KetQuaChinhXac.Value = num2str(fx(diemdaoham));
        switch phuongphap
            case 'Xấp xỉ tiến'
                kq = xapxitien(y,i,saiso,h);
                disp(kq);
            case 'Xấp xỉ lùi'
                kq = xapxilui(y,i,saiso,h);
            case 'Xấp xỉ trung tâm'
                kq = xapxitrungtam(y,i,saiso,h);
        end
                app.DaoHam_KetQua.Value = num2str(kq);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nUIFigure = uifigure;
            app.nUIFigure.Name = 'Đồ án';
            app.TabGroup = uitabgroup(app.nUIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Nghiem_NhapPhuongTrinh = uieditfield(app.NghiemTab, 'text');
            app.KhongphnlyabEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlyabEditFieldLabel.Text = 'Khoảng phân ly [a,b]';
            app.Nghiem_KhoangPhanLy_a = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_b = uieditfield(app.NghiemTab, 'numeric');
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.Nghiem_SaiSoChoPhep = uieditfield(app.NghiemTab, 'numeric');
            app.PhngphptmDropDownLabel = uilabel(app.NghiemTab);
            app.PhngphptmDropDownLabel.Text = 'Phương pháp tìm';
            app.Nghiem_PhuongPhap = uidropdown(app.NghiemTab);
            app.Nghiem_PhuongPhap.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.Nghiem_PhuongPhap.Value = 'Chia đôi';
            app.KtqunghimTextAreaLabel = uilabel(app.NghiemTab);
            app.KtqunghimTextAreaLabel.Text = 'Kết quả nghiệm';
            app.Nghiem_KetQua = uitextarea(app.NghiemTab);
            app.Nghiem_KetQua.Editable = 'off';
            app.Nghiem_DoThi = uiaxes(app.NghiemTab);
            title(app.Nghiem_DoThi, 'Đồ thị hàm số')
            xlabel(app.Nghiem_DoThi, 'X')
            ylabel(app.Nghiem_DoThi, 'Y')
            app.SlnlpTextAreaLabel = uilabel(app.NghiemTab);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.Nghiem_SoLanLap = uitextarea(app.NghiemTab);
            app.Nghiem_SoLanLap.Editable = 'off';
            app.NhpfpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpfpEditFieldLabel.Text = 'Nhập fp';
            app.Nghiem_Nhapfp = uieditfield(app.NghiemTab, 'text');
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'Nội suy';
            app.NhpdliuxyLabel = uilabel(app.NoiSuyTab);
            app.NhpdliuxyLabel.Text = 'Nhập dữ liệu x, y';
            app.NoiSuy_x = uieditfield(app.NoiSuyTab, 'numeric');
            app.PhngphpnisuyLabel = uilabel(app.NoiSuyTab);
            app.PhngphpnisuyLabel.Text = 'Phương pháp nội suy';
            app.NoiSuy_PhuongPhap = uidropdown(app.NoiSuyTab);
            app.NoiSuy_PhuongPhap.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PhuongPhap.Value = 'Newton';
            app.NoiSuy_y = uieditfield(app.NoiSuyTab, 'numeric');
            app.GitrnisuyLabel = uilabel(app.NoiSuyTab);
            app.GitrnisuyLabel.Text = 'Giá trị nội suy';
            app.NoiSuy_GiaTriNoiSuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.NoiSuy_DoThi = uiaxes(app.NoiSuyTab);
            title(app.NoiSuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.NoiSuy_DoThi, 'X')
            ylabel(app.NoiSuy_DoThi, 'Y')
            app.PhngtrnhLabel = uilabel(app.NoiSuyTab);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.NoiSuy_DaThuc = uitextarea(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel = uilabel(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.NoiSuy_KetQua = uitextarea(app.NoiSuyTab);
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi quy';
            app.HoiQuy_y = uieditfield(app.HoiQuyTab, 'numeric');
            app.NhpdliuxyLabel_2 = uilabel(app.HoiQuyTab);
            app.NhpdliuxyLabel_2.Text = 'Nhập dữ liệu x, y';
            app.HoiQuy_x = uieditfield(app.HoiQuyTab, 'numeric');
            app.GitrcnsuyonLabel = uilabel(app.HoiQuyTab);
            app.GitrcnsuyonLabel.Text = 'Giá trị cần suy đoán';
            app.HoiQuy_GiaTriSuyDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.PhngphpnisuyLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngphpnisuyLabel_2.Text = 'Phương pháp nội suy';
            app.HoiQuy_PhuongPhap = uidropdown(app.HoiQuyTab);
            app.HoiQuy_PhuongPhap.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.HoiQuy_PhuongPhap.Value = 'Tuyến tính';
            app.PhngtrnhLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngtrnhLabel_2.Text = 'Phương trình';
            app.HoiQuy_PhuongTrinh = uitextarea(app.HoiQuyTab);
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_KetQua = uitextarea(app.HoiQuyTab);
            app.HoiQuy_DoThi = uiaxes(app.HoiQuyTab);
            title(app.HoiQuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.HoiQuy_DoThi, 'X')
            ylabel(app.HoiQuy_DoThi, 'Y')
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo hàm';
            app.NhphmsLabel = uilabel(app.DaoHamTab);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.DaoHam_HamSo = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_HamSo.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_HamSo.Value = '1/sqrt(1-x^2)';
            app.GitrcnohmLabel = uilabel(app.DaoHamTab);
            app.GitrcnohmLabel.Text = 'Giá trị cần đạo hàm';
            app.DaoHam_GiaTri = uieditfield(app.DaoHamTab, 'numeric');
            app.DaoHam_GiaTri.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_GiaTri.Value = 0.5;
            app.GitrsaisSwitchLabel = uilabel(app.DaoHamTab);
            app.GitrsaisSwitchLabel.Text = 'Giá trị sai số';
            app.DaoHam_GiaTriSaiSo = uiswitch(app.DaoHamTab, 'slider');
            app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
            app.DaoHam_GiaTriSaiSo.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_GiaTriSaiSo.Value = 'O(h)';
            app.PhngphpnisuyLabel_3 = uilabel(app.DaoHamTab);
            app.PhngphpnisuyLabel_3.Text = 'Phương pháp nội suy';
            app.DaoHam_PhuongPhap = uidropdown(app.DaoHamTab);
            app.DaoHam_PhuongPhap.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DaoHam_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_PhuongPhap.Value = 'Xấp xỉ tiến';
            app.KtquTextAreaLabel = uilabel(app.DaoHamTab);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.DaoHam_KetQua = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQua.Editable = 'off';
            app.KtquchnhxcLabel = uilabel(app.DaoHamTab);
            app.KtquchnhxcLabel.Text = 'Kết quả chính xác';
            app.DaoHam_KetQuaChinhXac = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQuaChinhXac.Editable = 'off';
            app.NhpdliuxyEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpdliuxyEditFieldLabel.Text = 'Nhập dữ liệu x, y';
            app.DaoHam_x = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_x.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_x.Value = '0.1 0.3 0.5 0.7 0.9';
            app.DaoHam_y = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_y.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_y.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.NhpdliuxyLabel_4 = uilabel(app.TichPhanTab);
            app.NhpdliuxyLabel_4.Text = 'Nhập dữ liệu x, y';
            app.TichPhan_x = uieditfield(app.TichPhanTab, 'numeric');
            app.TichPhan_y = uieditfield(app.TichPhanTab, 'numeric');
            app.NhphmsLabel_2 = uilabel(app.TichPhanTab);
            app.NhphmsLabel_2.Text = 'Nhập hàm số';
            app.TichPhan_HamSo = uieditfield(app.TichPhanTab, 'text');
            app.NhpcnabLabel = uilabel(app.TichPhanTab);
            app.NhpcnabLabel.Text = 'Nhập cận [a,b]';
            app.TichPhan_Can = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TichPhan_N = uitextarea(app.TichPhanTab);
            app.PhngphpnisuyLabel_4 = uilabel(app.TichPhanTab);
            app.PhngphpnisuyLabel_4.Text = 'Phương pháp nội suy';
            app.TIchPhan_PhuongPhap = uidropdown(app.TichPhanTab);
            app.TIchPhan_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TIchPhan_PhuongPhap.Value = 'Hình thang';
            app.KtquTextAreaLabel_2 = uilabel(app.TichPhanTab);
            app.KtquTextAreaLabel_2.Text = 'Kết quả';
            app.TichPhan_KetQua = uitextarea(app.TichPhanTab);
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới thiệu nhóm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DoAnMatlab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nUIFigure)
        end
    end
end